classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        Tab_3                     matlab.ui.container.Tab
        Panel_2                   matlab.ui.container.Panel
        mEditFieldLabel           matlab.ui.control.Label
        JD_displayment_size       matlab.ui.control.NumericEditField
        JD_displayment_choose     matlab.ui.control.CheckBox
        sLabel_2                  matlab.ui.control.Label
        JD_displayment_time       matlab.ui.control.NumericEditField
        sLabel                    matlab.ui.control.Label
        JD_displayment_interval   matlab.ui.control.NumericEditField
        JD_figure                 matlab.ui.control.UIAxes
        Panel_3                   matlab.ui.container.Panel
        EditField_2Label          matlab.ui.control.Label
        JD_latitude               matlab.ui.control.NumericEditField
        EditField_3Label          matlab.ui.control.Label
        JD_altitude               matlab.ui.control.NumericEditField
        pitchLabel                matlab.ui.control.Label
        JD_pitch                  matlab.ui.control.DropDown
        rollLabel                 matlab.ui.control.Label
        JD_roll                   matlab.ui.control.DropDown
        yawLabel                  matlab.ui.control.Label
        JD_yaw                    matlab.ui.control.DropDown
        EditFieldLabel            matlab.ui.control.Label
        JD_longitude              matlab.ui.control.NumericEditField
        Label_18                  matlab.ui.control.Label
        sample_rate               matlab.ui.control.NumericEditField
        Panel                     matlab.ui.container.Panel
        JD_electric_choose        matlab.ui.control.CheckBox
        AEditFieldLabel           matlab.ui.control.Label
        JD_electric_size          matlab.ui.control.NumericEditField
        sEditField_3Label         matlab.ui.control.Label
        JD_electric_time          matlab.ui.control.NumericEditField
        sEditField_4Label         matlab.ui.control.Label
        JD_electric_interval      matlab.ui.control.NumericEditField
        createJDData              matlab.ui.control.Button
        JD_all_variable           matlab.ui.control.ListBox
        Tab_4                     matlab.ui.container.Tab
        create_PFdata             matlab.ui.control.Button
        Label_10                  matlab.ui.control.Label
        PF_Start_longitude        matlab.ui.control.NumericEditField
        Label_11                  matlab.ui.control.Label
        PF_End_longitude          matlab.ui.control.NumericEditField
        Label_12                  matlab.ui.control.Label
        PF_Start_latitude         matlab.ui.control.NumericEditField
        Label_13                  matlab.ui.control.Label
        PF_End_latitude           matlab.ui.control.NumericEditField
        Label_14                  matlab.ui.control.Label
        PF_Start_altitude         matlab.ui.control.NumericEditField
        Label_15                  matlab.ui.control.Label
        PF_End_altitude           matlab.ui.control.NumericEditField
        Label_16                  matlab.ui.control.Label
        PF_velocity               matlab.ui.control.NumericEditField
        Label_17                  matlab.ui.control.Label
        PF_sample                 matlab.ui.control.NumericEditField
        PF_figure                 matlab.ui.control.UIAxes
        PF_all_variable           matlab.ui.control.ListBox
        Panel_4                   matlab.ui.container.Panel
        mEditField_2Label         matlab.ui.control.Label
        PF_displayment_size       matlab.ui.control.NumericEditField
        PF_displayment_choose     matlab.ui.control.CheckBox
        sEditField_5Label         matlab.ui.control.Label
        PF_displayment_time       matlab.ui.control.NumericEditField
        sEditField_6Label         matlab.ui.control.Label
        PF_displayment_interval   matlab.ui.control.NumericEditField
        Panel_5                   matlab.ui.container.Panel
        PF_electric_choose        matlab.ui.control.CheckBox
        AEditField_2Label         matlab.ui.control.Label
        PF_electric_size          matlab.ui.control.NumericEditField
        sEditField_7Label         matlab.ui.control.Label
        PF_electric_time          matlab.ui.control.NumericEditField
        sEditField_8Label         matlab.ui.control.Label
        PF_electric_interval      matlab.ui.control.NumericEditField
        Tab                       matlab.ui.container.Tab
        loadOriginalDataButton    matlab.ui.control.Button
        downSampleButton          matlab.ui.control.Button
        SampleDropDown            matlab.ui.control.DropDown
        Label_8                   matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        Tab2                      matlab.ui.container.Tab
        XYZUIAxes                 matlab.ui.control.UIAxes
        Label_5                   matlab.ui.control.Label
        CircleDataStartEditField  matlab.ui.control.NumericEditField
        Label_6                   matlab.ui.control.Label
        CircleDataEndEditField    matlab.ui.control.NumericEditField
        ShowCircleDataButton      matlab.ui.control.Button
        CircleDataUITable         matlab.ui.control.Table
        Label_7                   matlab.ui.control.Label
        CircleDataTypeDropDown    matlab.ui.control.DropDown
        CreateCircleDataButton    matlab.ui.control.Button
        CreateCoeffButton         matlab.ui.control.Button
        TLCheckBox                matlab.ui.control.CheckBox
        EECheckBox                matlab.ui.control.CheckBox
        MDCheckBox                matlab.ui.control.CheckBox
        Label_9                   matlab.ui.control.Label
        Tab3                      matlab.ui.container.Tab
        AutoSelectButton          matlab.ui.control.Button
        selectCoefficient         matlab.ui.control.Label
        ManualSelectButton        matlab.ui.control.Button
        CompensatedButton         matlab.ui.control.Button
        deleteCoeffButton         matlab.ui.control.Button
        deleteAllCoeffButton      matlab.ui.control.Button
        figureTabGroup            matlab.ui.container.TabGroup
        TLTab                     matlab.ui.container.Tab
        EETab                     matlab.ui.container.Tab
        EEUIAxes                  matlab.ui.control.UIAxes
        MDTab                     matlab.ui.container.Tab
        MDUIAxes                  matlab.ui.control.UIAxes
        CTTab                     matlab.ui.container.Tab
        CTUIAxes                  matlab.ui.control.UIAxes
        IndexUITable              matlab.ui.control.Table
        TLUIAxes                  matlab.ui.control.UIAxes
        CoefficientDropDown       matlab.ui.control.DropDown
        userState                 matlab.ui.control.Label
    end


    properties (Access = private)
        OriginalData % Description
        OriginalData_downSampled
        GeomagneticField
        RemoveGeomagneticData
        Date = '20180610'
        CircleDataNum
        Coefficients
    end


    methods (Access = private)

        % Button pushed function: loadOriginalDataButton
        function loadOriginalDataButtonPushed(app, event)
            [filename,pathname] = uigetfile('.txt');
            if isequal(filename,0)
                app.userState.Text = '用户取消选择';
            else
                %mdata_struct = importdata([pathname filename])
                %mdata = mdata_struct.data;
                mdata = importdata([pathname filename]);
                app.OriginalData = mdata(all(~isnan(mdata),2),:);
                app.userState.Text = '导入数据成功';
            end
            addpath(genpath('./data/'),'./function/','./filter/',genpath('./Coefficient/'),genpath('./Figure/'),genpath('./Index/'));
        end

        % Button pushed function: downSampleButton
        function downSampleButtonPushed(app, event)
            sampleValue = app.SampleDropDown.Value;
            value = str2double(sampleValue);
            app.OriginalData_downSampled = downSample(app.OriginalData,value);
            plot(app.XYZUIAxes,app.OriginalData_downSampled(:,2));
            hold(app.XYZUIAxes,'on');
            plot(app.XYZUIAxes,app.OriginalData_downSampled(:,3));
            hold(app.XYZUIAxes,'on');
            plot(app.XYZUIAxes,app.OriginalData_downSampled(:,4));
            app.userState.Text = '降采样结束';
        end

        % Button pushed function: create_PFdata
        function create_PFdataPushed(app, event)
            disp('start create_PFdata');
            addpath(genpath('./data/'),'./function/','./filter/',genpath('./Coefficient/'),genpath('./Figure/'),genpath('./Index/'));
            Start_Longitude = app.PF_Start_longitude.Value;
            Start_Latitude = app.PF_Start_latitude.Value;
            Start_Height = app.PF_Start_altitude.Value;
            End_Longitude = app.PF_End_longitude.Value;
            End_Latitude = app.PF_End_latitude.Value;
            End_Height = app.PF_End_altitude.Value;
            velocity = app.PF_velocity.Value;
            sample_f = app.PF_sample.Value;
            [lldistance,llhdistance] = calculateDistance(Start_Longitude,Start_Latitude,Start_Height,End_Longitude,End_Latitude,End_Height);
            d = velocity/sample_f;
            %             d=0;
            saveLLH = [];
            for i = 0:d:llhdistance
                [x,y,z]=calculateLonLatHei(Start_Longitude,Start_Latitude,Start_Height,End_Longitude,End_Latitude,End_Height,i,llhdistance,lldistance);
                saveLLH = [saveLLH;x y z];
            end
            saveLLH = addAwgn(saveLLH,1);
            pwd
            %             save([pwd,'\data\OriginalData\LLH.mat'],'saveLLH','-ascii','-double','-tabs');
            save([pwd,'\data\OriginalData\LLH.mat'],'saveLLH');
            disp('经纬高已生成');
            total_geomag = create_geomag(saveLLH);
            save([pwd,'\data\OriginalData\totalGeomag.mat'],'total_geomag');
            app.userState.Text ='地磁场数据生成';
            if app.PF_electric_choose == 1
               PF_electric_size = app.PF_electric_size.Value;
               PF_electric_time = app.PF_electric_time.Value;
               PF_electric_interval = app.PF_electric_interval.Value;
            end
            if app.PF_displayment_choose == 1
                PF_displayment_size = app.PF_displayment_size.Value;
                PF_displayment_time = app.PF_displayment_time.Value;
                PF_displayment_interval = app.PF_displayment_interval.Value;
            end
            %             GeomagneticField;
            %             [xyz, h, dec, dip, f] = wrldmagm(height, lat, lon, dyear, '2015');
        end

        % Button pushed function: ShowCircleDataButton
        function ShowCircleDataButtonPushed(app, event)
           startnum = app.CircleDataStartEditField.Value;
           endnum = app.CircleDataEndEditField.Value;
%            datatype = app.CircleDataTypeDropDown.Value;
           if strcmp(app.CircleDataTypeDropDown.Value,'1 机动')
               dataType = 1;
           elseif strcmp(app.CircleDataTypeDropDown.Value,'2 平飞')
               dataType = 2;
           elseif strcmp(app.CircleDataTypeDropDown.Value,'3 测线')
               dataType = 3;
           end
           app.CircleDataUITable.Data = [app.CircleDataUITable.Data ; startnum endnum dataType];
           app.userState.Text = '数据导入成功';
        end

        % Button pushed function: CreateCircleDataButton
        function CreateCircleDataButtonPushed(app, event)
            tableData = app.CircleDataUITable.Data;
            app.CircleDataNum = tableData;
            savepath = [pwd,'\data\CircleData\',app.Date,'\'];
            name = 'circleData.mat';
            savefile(savepath,name,tableData);
            app.userState.Text = '数据分段成功';
        end

        % Button pushed function: CreateCoeffButton
        function CreateCoeffButtonPushed(app, event)
%             app.RemoveGeomagneticData = app.OriginalData_downSampled - app.GeomagneticField;
            app.RemoveGeomagneticData = app.OriginalData_downSampled    %%  后期修改!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            savepath = [pwd,'\Coefficient\',app.Date,'\'];
            
            if app.EECheckBox.Value == 1 && app.MDCheckBox.Value == 1
                
            elseif app.EECheckBox.Value == 1 && app.MDCheckBox.Value == 0
                
            elseif app.EECheckBox.Value == 0 && app.MDCheckBox.Value == 1
                
            elseif app.EECheckBox.Value == 0 && app.MDCheckBox.Value == 0
                [vCosX, vCosY, vCosZ] = calculate_cosine(app.RemoveGeomagneticData(:,2),app.RemoveGeomagneticData(:,3),app.RemoveGeomagneticData(:,4));
                [vDCosX, vDCosY, vDCosZ] = calculate_derivate(vCosX, vCosY, vCosZ);
                mTLModel = [vCosX, vCosY, vCosZ, vCosX.^2, vCosX.*vCosY, vCosX.*vCosZ, vCosY.^2, vCosY.*vCosZ, ...
                    vCosX.*vDCosX, vCosX.*vDCosY, vCosX.*vDCosZ, vCosY.*vDCosX, vCosY.*vDCosY, vCosY.*vDCosZ,...
                    vCosZ.*vDCosX, vCosZ.*vDCosY];
                for i =1:size(app.CircleDataNum,1)
                    if app.CircleDataNum(i,3) == 1   %% 机动段
                        vCoefficients = recursive_least_square_hit(app.RemoveGeomagneticData(app.CircleDataNum(i,1):app.CircleDataNum(i,2),1), mTLModel(app.CircleDataNum(i,1):app.CircleDataNum(i,2),:))
                        name = [app.Date,'Coefficient',num2str(i)];
                        Coefficient.name = name;
                        Coefficient.value = vCoefficients;
                        app.Coefficients = [app.Coefficients; Coefficient];
                        savename = [name,'.mat'];
                        savefile(savepath,savename,vCoefficients);
                    end
                end
            end

            for i = 1:size(app.Coefficients,1)
                app.CoefficientDropDown.Items = [app.CoefficientDropDown.Items,app.Coefficients.name];
            end
            
            app.userState.Text = '系数生成成功';
        end

        % Button pushed function: ManualSelectButton
        function ManualSelectButtonPushed(app, event)
            [filename,pathname] = uigetfile('.mat');
            Coefficient.name = filename;
            mdata_struct = importdata([pathname filename]);
            mdata = mdata_struct.data;
            Coefficient.value = mdata;
            app.Coefficients = [app.Coefficients;Coefficient];
            app.CoefficientDropDown.Items = [app.CoefficientDropDown.Items,Coefficient.name];
            
        end

        % Button pushed function: AutoSelectButton
        function AutoSelectButtonPushed(app, event)
            
        end

        % Button pushed function: deleteCoeffButton
        function deleteCoeffButtonPushed(app, event)
            
        end

        % Button pushed function: deleteAllCoeffButton
        function deleteAllCoeffButtonPushed(app, event)
            
        end

        % Button pushed function: CompensatedButton
        function CompensatedButtonPushed(app, event)
            
        end

        % Button pushed function: createJDData
        function createJDDataButtonPushed(app, event)
            sample_rate = app.sample_rate.Value;
            if app.JD_electric_choose.Value == 1
               JD_electric_size = app.JD_electric_size.Value;
               JD_electric_time = app.JD_electric_time.Value;
               JD_electric_interval = app.JD_electric_interval.Value;
               total_elec = electornic(JD_electric_size,JD_electric_time,JD_electric_interval,sample_rate);
               plot(app.JD_figure, total_elec,'-r');
            elseif app.JD_displayment_choose.Value == 1
                JD_displayment_size = app.JD_displayment_size.Value;
                JD_displayment_time = app.JD_displayment_time.Value;
                JD_displayment_interval = app.JD_displayment_interval.Value;
                total_disp = displapment(JD_displayment_size,JD_displayment_time,JD_displayment_interval,sample_rate);
                plot(app.JD_figure, total_disp,'-r');
            else
                %(app.JD_electric_choose == 0) && (app.JD_displayment_choose == 0)
                JD_longitude = app.JD_longitude.Value;
                JD_latitude = app.JD_latitude.Value;
                JD_altitdue = app.JD_altitude.Value;
                JD_pitch = app.JD_pitch;
                JD_roll = app.JD_roll;
                JD_yaw = app.JD_yaw;
                %data_file = [JD_longitude JD_latitude JD_altitdue 0 10.97596389 -0.158272237 JD_yaw JD_pitch JD_roll];
                data(1,1) = JD_longitude;
                data(1,2) = JD_latitude;
                data(1,3) = JD_altitdue;
                data(1,4) = 0;
                data(1,5) = 10.97596389;
                data(1,6) = -0.158272237;
                data(1,7) = str2num(JD_yaw.Value);
                data(1,8) = str2num(JD_pitch.Value);
                data(1,9) = str2num(JD_roll.Value);
                [~, ~, ~, ~, ~, ~, ~,~,~,total_1,~,~,~,~] = cos_angel(data,sample_rate);
                data(1,4) = 90;
                [~, ~, ~, ~, ~, ~, ~,~,~,total_2,~,~,~,~] = cos_angel(data,sample_rate);
                data(1,4) = 180;
                [~, ~, ~, ~, ~, ~, ~,~,~,total_3,~,~,~,~] = cos_angel(data,sample_rate);
                data(1,4) = 270;
                [~, ~, ~, ~, ~, ~, ~,~,~,total_4,~,~,~,~] = cos_angel(data,sample_rate);
                total = [total_1' total_2' total_3' total_4'];
                p=plot(app.JD_figure, total,'-r');
            
                %zoom(app.UIAxes,'on');
                app.UIAxes.XLim = [0 2000];
                app.UIAxes.YLim = [35 50];
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '磁干扰数据仿真';
            app.TabGroup2 = uitabgroup(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '机动圈数据仿真';
            app.Panel_2 = uipanel(app.Tab_3);
            app.Panel_2.Title = '机械位移数据仿真';
            app.mEditFieldLabel = uilabel(app.Panel_2);
            app.mEditFieldLabel.Text = '钢索位移量（m）';
            app.JD_displayment_size = uieditfield(app.Panel_2, 'numeric');
            app.JD_displayment_choose = uicheckbox(app.Panel_2);
            app.JD_displayment_choose.Text = '选择';
            app.sLabel_2 = uilabel(app.Panel_2);
            app.sLabel_2.Text = '持续时间（s）';
            app.JD_displayment_time = uieditfield(app.Panel_2, 'numeric');
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = '时间间隔（s）';
            app.JD_displayment_interval = uieditfield(app.Panel_2, 'numeric');
            app.JD_figure = uiaxes(app.Tab_3);
            xlabel(app.JD_figure, 'X')
            ylabel(app.JD_figure, 'Y')
            app.Panel_3 = uipanel(app.Tab_3);
            app.EditField_2Label = uilabel(app.Panel_3);
            app.EditField_2Label.Text = '纬度';
            app.JD_latitude = uieditfield(app.Panel_3, 'numeric');
            app.EditField_3Label = uilabel(app.Panel_3);
            app.EditField_3Label.Text = '高度';
            app.JD_altitude = uieditfield(app.Panel_3, 'numeric');
            app.pitchLabel = uilabel(app.Panel_3);
            app.pitchLabel.Text = 'pitch角';
            app.JD_pitch = uidropdown(app.Panel_3);
            app.JD_pitch.Items = {'5', '10', '15', '20'};
            app.JD_pitch.ItemsData = {'5', '10', '15', '20'};
            app.JD_pitch.Value = '5';
            app.rollLabel = uilabel(app.Panel_3);
            app.rollLabel.Text = 'roll角';
            app.JD_roll = uidropdown(app.Panel_3);
            app.JD_roll.Items = {'5', '10', '15', '20'};
            app.JD_roll.ItemsData = {'5', '10', '15', '20'};
            app.JD_roll.Value = '5';
            app.yawLabel = uilabel(app.Panel_3);
            app.yawLabel.Text = 'yaw角';
            app.JD_yaw = uidropdown(app.Panel_3);
            app.JD_yaw.Items = {'5', '10', '15', '20'};
            app.JD_yaw.ItemsData = {'5', '10', '15', '20'};
            app.JD_yaw.Value = '5';
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = '经度';
            app.JD_longitude = uieditfield(app.Panel_3, 'numeric');
            app.Label_18 = uilabel(app.Panel_3);
            app.Label_18.Text = '采样率';
            app.sample_rate = uieditfield(app.Panel_3, 'numeric');
            app.Panel = uipanel(app.Tab_3);
            app.Panel.Title = '用电设备数据仿真';
            app.JD_electric_choose = uicheckbox(app.Panel);
            app.JD_electric_choose.Text = '选择';
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Text = '电流大小（A）';
            app.JD_electric_size = uieditfield(app.Panel, 'numeric');
            app.sEditField_3Label = uilabel(app.Panel);
            app.sEditField_3Label.Text = '持续时间（s）';
            app.JD_electric_time = uieditfield(app.Panel, 'numeric');
            app.sEditField_4Label = uilabel(app.Panel);
            app.sEditField_4Label.Text = '时间间隔（s）';
            app.JD_electric_interval = uieditfield(app.Panel, 'numeric');
            app.createJDData = uibutton(app.Tab_3, 'push');
            app.createJDData.ButtonPushedFcn = createCallbackFcn(app, @createJDDataButtonPushed, true);
            app.createJDData.Text = '生成机动圈数据';
            app.JD_all_variable = uilistbox(app.Tab_3);
            app.JD_all_variable.Items = {'总场', '地磁场', '三分量', 'Item 4'};
            app.JD_all_variable.Multiselect = 'on';
            app.JD_all_variable.Value = {'总场'};
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '平飞段数据仿真';
            app.create_PFdata = uibutton(app.Tab_4, 'push');
            app.create_PFdata.ButtonPushedFcn = createCallbackFcn(app, @create_PFdataPushed, true);
            app.create_PFdata.Text = '生成平飞段数据';
            app.Label_10 = uilabel(app.Tab_4);
            app.Label_10.Text = '起点经度';
            app.PF_Start_longitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_longitude.Limits = [-180 180];
            app.PF_Start_longitude.ValueDisplayFormat = '%.10f';
            app.PF_Start_longitude.Value = 110.1292467;
            app.Label_11 = uilabel(app.Tab_4);
            app.Label_11.Text = '终点经度';
            app.PF_End_longitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_longitude.Limits = [-180 180];
            app.PF_End_longitude.ValueDisplayFormat = '%.10f';
            app.PF_End_longitude.Value = 110.1173971;
            app.Label_12 = uilabel(app.Tab_4);
            app.Label_12.Text = '起点纬度';
            app.PF_Start_latitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_latitude.Limits = [-90 90];
            app.PF_Start_latitude.ValueDisplayFormat = '%.10f';
            app.PF_Start_latitude.Value = 17.15710873;
            app.Label_13 = uilabel(app.Tab_4);
            app.Label_13.Text = '终点纬度';
            app.PF_End_latitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_latitude.Limits = [-90 90];
            app.PF_End_latitude.ValueDisplayFormat = '%.10f';
            app.PF_End_latitude.Value = 17.40880123;
            app.Label_14 = uilabel(app.Tab_4);
            app.Label_14.Text = '起点高度';
            app.PF_Start_altitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_altitude.Limits = [0 Inf];
            app.PF_Start_altitude.ValueDisplayFormat = '%.3f';
            app.PF_Start_altitude.Value = 3201.379;
            app.Label_15 = uilabel(app.Tab_4);
            app.Label_15.Text = '终点高度';
            app.PF_End_altitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_altitude.Limits = [0 Inf];
            app.PF_End_altitude.ValueDisplayFormat = '%.3f';
            app.PF_End_altitude.Value = 3568;
            app.Label_16 = uilabel(app.Tab_4);
            app.Label_16.Text = '载体速度';
            app.PF_velocity = uieditfield(app.Tab_4, 'numeric');
            app.PF_velocity.Value = 60;
            app.Label_17 = uilabel(app.Tab_4);
            app.Label_17.Text = '采样率';
            app.PF_sample = uieditfield(app.Tab_4, 'numeric');
            app.PF_sample.Value = 10;
            app.PF_figure = uiaxes(app.Tab_4);
            xlabel(app.PF_figure, 'X')
            ylabel(app.PF_figure, 'Y')
            app.PF_all_variable = uilistbox(app.Tab_4);
            app.PF_all_variable.Items = {'经纬度', '高度', '地磁场', '总场', '用电设备磁干扰', '机械位移磁干扰'};
            app.PF_all_variable.Multiselect = 'on';
            app.PF_all_variable.Value = {'经纬度'};
            app.Panel_4 = uipanel(app.Tab_4);
            app.Panel_4.Title = '机械位移数据仿真';
            app.mEditField_2Label = uilabel(app.Panel_4);
            app.mEditField_2Label.Text = '钢索位移量（m）';
            app.PF_displayment_size = uieditfield(app.Panel_4, 'numeric');
            app.PF_displayment_choose = uicheckbox(app.Panel_4);
            app.PF_displayment_choose.Text = '选择';
            app.sEditField_5Label = uilabel(app.Panel_4);
            app.sEditField_5Label.Text = '持续时间（s）';
            app.PF_displayment_time = uieditfield(app.Panel_4, 'numeric');
            app.sEditField_6Label = uilabel(app.Panel_4);
            app.sEditField_6Label.Text = '时间间隔（s）';
            app.PF_displayment_interval = uieditfield(app.Panel_4, 'numeric');
            app.Panel_5 = uipanel(app.Tab_4);
            app.Panel_5.Title = '用电设备数据仿真';
            app.PF_electric_choose = uicheckbox(app.Panel_5);
            app.PF_electric_choose.Text = '选择';
            app.AEditField_2Label = uilabel(app.Panel_5);
            app.AEditField_2Label.Text = '电流大小（A）';
            app.PF_electric_size = uieditfield(app.Panel_5, 'numeric');
            app.sEditField_7Label = uilabel(app.Panel_5);
            app.sEditField_7Label.Text = '持续时间（s）';
            app.PF_electric_time = uieditfield(app.Panel_5, 'numeric');
            app.sEditField_8Label = uilabel(app.Panel_5);
            app.sEditField_8Label.Text = '时间间隔（s）';
            app.PF_electric_interval = uieditfield(app.Panel_5, 'numeric');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.loadOriginalDataButton = uibutton(app.Tab, 'push');
            app.loadOriginalDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadOriginalDataButtonPushed, true);
            app.loadOriginalDataButton.Text = '读取原始数据';
            app.downSampleButton = uibutton(app.Tab, 'push');
            app.downSampleButton.ButtonPushedFcn = createCallbackFcn(app, @downSampleButtonPushed, true);
            app.downSampleButton.Text = '降采样';
            app.SampleDropDown = uidropdown(app.Tab);
            app.SampleDropDown.Items = {'10', '50', '100', '200', '300HZ'};
            app.SampleDropDown.Editable = 'on';
            app.SampleDropDown.Value = '10';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = '降采样倍数：';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.XYZUIAxes = uiaxes(app.Tab2);
            title(app.XYZUIAxes, 'Title')
            xlabel(app.XYZUIAxes, 'X')
            ylabel(app.XYZUIAxes, 'Y')
            app.Label_5 = uilabel(app.Tab2);
            app.Label_5.Text = '起点';
            app.CircleDataStartEditField = uieditfield(app.Tab2, 'numeric');
            app.CircleDataStartEditField.Limits = [0 Inf];
            app.Label_6 = uilabel(app.Tab2);
            app.Label_6.Text = '终点';
            app.CircleDataEndEditField = uieditfield(app.Tab2, 'numeric');
            app.CircleDataEndEditField.Limits = [0 Inf];
            app.ShowCircleDataButton = uibutton(app.Tab2, 'push');
            app.ShowCircleDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCircleDataButtonPushed, true);
            app.ShowCircleDataButton.Text = '显示';
            app.CircleDataUITable = uitable(app.Tab2);
            app.CircleDataUITable.ColumnName = {'起点'; '终点'; '类型'};
            app.CircleDataUITable.RowName = {};
            app.Label_7 = uilabel(app.Tab2);
            app.Label_7.Text = '类型';
            app.CircleDataTypeDropDown = uidropdown(app.Tab2);
            app.CircleDataTypeDropDown.Items = {'1 机动', '2 平飞', '3 测线'};
            app.CircleDataTypeDropDown.Value = '1 机动';
            app.CreateCircleDataButton = uibutton(app.Tab2, 'push');
            app.CreateCircleDataButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCircleDataButtonPushed, true);
            app.CreateCircleDataButton.Text = '数据分段';
            app.CreateCoeffButton = uibutton(app.Tab2, 'push');
            app.CreateCoeffButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCoeffButtonPushed, true);
            app.CreateCoeffButton.Text = '生成系数';
            app.TLCheckBox = uicheckbox(app.Tab2);
            app.TLCheckBox.Enable = 'off';
            app.TLCheckBox.Text = 'T-L';
            app.TLCheckBox.Value = true;
            app.EECheckBox = uicheckbox(app.Tab2);
            app.EECheckBox.Text = '用电设备干扰';
            app.MDCheckBox = uicheckbox(app.Tab2);
            app.MDCheckBox.Text = '机械位移干扰';
            app.Label_9 = uilabel(app.Tab2);
            app.Label_9.Text = '干扰项选择：';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AutoSelectButton = uibutton(app.Tab3, 'push');
            app.AutoSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSelectButtonPushed, true);
            app.AutoSelectButton.Text = '自动选择';
            app.selectCoefficient = uilabel(app.Tab3);
            app.selectCoefficient.Text = '系数选择：';
            app.ManualSelectButton = uibutton(app.Tab3, 'push');
            app.ManualSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSelectButtonPushed, true);
            app.ManualSelectButton.Text = '手动选择';
            app.CompensatedButton = uibutton(app.Tab3, 'push');
            app.CompensatedButton.ButtonPushedFcn = createCallbackFcn(app, @CompensatedButtonPushed, true);
            app.CompensatedButton.Text = '补偿';
            app.deleteCoeffButton = uibutton(app.Tab3, 'push');
            app.deleteCoeffButton.ButtonPushedFcn = createCallbackFcn(app, @deleteCoeffButtonPushed, true);
            app.deleteCoeffButton.Text = '单项删除';
            app.deleteAllCoeffButton = uibutton(app.Tab3, 'push');
            app.deleteAllCoeffButton.ButtonPushedFcn = createCallbackFcn(app, @deleteAllCoeffButtonPushed, true);
            app.deleteAllCoeffButton.Text = '清空';
            app.figureTabGroup = uitabgroup(app.Tab3);
            app.TLTab = uitab(app.figureTabGroup);
            app.TLTab.Title = 'T-L';
            app.EETab = uitab(app.figureTabGroup);
            app.EETab.Title = '用电设备';
            app.EEUIAxes = uiaxes(app.EETab);
            title(app.EEUIAxes, 'Title')
            xlabel(app.EEUIAxes, 'X')
            ylabel(app.EEUIAxes, 'Y')
            app.MDTab = uitab(app.figureTabGroup);
            app.MDTab.Title = '机械位移';
            app.MDUIAxes = uiaxes(app.MDTab);
            title(app.MDUIAxes, 'Title')
            xlabel(app.MDUIAxes, 'X')
            ylabel(app.MDUIAxes, 'Y')
            app.CTTab = uitab(app.figureTabGroup);
            app.CTTab.Title = '补偿后总场';
            app.CTUIAxes = uiaxes(app.CTTab);
            title(app.CTUIAxes, 'Title')
            xlabel(app.CTUIAxes, 'X')
            ylabel(app.CTUIAxes, 'Y')
            app.IndexUITable = uitable(app.Tab3);
            app.IndexUITable.ColumnName = {'系数'; '数据'; '5min30s'};
            app.IndexUITable.RowName = {};
            app.TLUIAxes = uiaxes(app.Tab3);
            title(app.TLUIAxes, 'Title')
            xlabel(app.TLUIAxes, 'X')
            ylabel(app.TLUIAxes, 'Y')
            app.CoefficientDropDown = uidropdown(app.Tab3);
            app.CoefficientDropDown.Items = {};
            app.CoefficientDropDown.Value = {};
            app.userState = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end