classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab_2                    matlab.ui.container.Tab
        TabGroup2                matlab.ui.container.TabGroup
        Tab_3                    matlab.ui.container.Tab
        Panel_2                  matlab.ui.container.Panel
        mEditFieldLabel          matlab.ui.control.Label
        JD_displayment_size      matlab.ui.control.NumericEditField
        JD_displayment_choose    matlab.ui.control.CheckBox
        sLabel_2                 matlab.ui.control.Label
        JD_displayment_time      matlab.ui.control.NumericEditField
        sLabel                   matlab.ui.control.Label
        JD_displayment_interval  matlab.ui.control.NumericEditField
        JD_figure                matlab.ui.control.UIAxes
        Panel_3                  matlab.ui.container.Panel
        EditField_2Label         matlab.ui.control.Label
        JD_latitude              matlab.ui.control.NumericEditField
        EditField_3Label         matlab.ui.control.Label
        JD_altitude              matlab.ui.control.NumericEditField
        pitchLabel               matlab.ui.control.Label
        JD_pitch                 matlab.ui.control.DropDown
        rollLabel                matlab.ui.control.Label
        JD_roll                  matlab.ui.control.DropDown
        yawLabel                 matlab.ui.control.Label
        JD_yaw                   matlab.ui.control.DropDown
        EditFieldLabel           matlab.ui.control.Label
        JD_longitude             matlab.ui.control.NumericEditField
        Label_18                 matlab.ui.control.Label
        JD_sample                matlab.ui.control.NumericEditField
        Panel                    matlab.ui.container.Panel
        JD_electric_choose       matlab.ui.control.CheckBox
        AEditFieldLabel          matlab.ui.control.Label
        JD_electric_size         matlab.ui.control.NumericEditField
        sEditField_3Label        matlab.ui.control.Label
        JD_electric_time         matlab.ui.control.NumericEditField
        sEditField_4Label        matlab.ui.control.Label
        JD_electric_interval     matlab.ui.control.NumericEditField
        createJDData             matlab.ui.control.Button
        JD_all_variable          matlab.ui.control.ListBox
        Button_plotJD            matlab.ui.control.Button
        CheckBox_reuse_2         matlab.ui.control.CheckBox
        Button_JD_pan            matlab.ui.control.Button
        Button_JD_large          matlab.ui.control.Button
        Tab_4                    matlab.ui.container.Tab
        create_PFdata            matlab.ui.control.Button
        Label_10                 matlab.ui.control.Label
        PF_Start_longitude       matlab.ui.control.NumericEditField
        Label_11                 matlab.ui.control.Label
        PF_End_longitude         matlab.ui.control.NumericEditField
        Label_12                 matlab.ui.control.Label
        PF_Start_latitude        matlab.ui.control.NumericEditField
        Label_13                 matlab.ui.control.Label
        PF_End_latitude          matlab.ui.control.NumericEditField
        Label_14                 matlab.ui.control.Label
        PF_Start_altitude        matlab.ui.control.NumericEditField
        Label_15                 matlab.ui.control.Label
        PF_End_altitude          matlab.ui.control.NumericEditField
        Label_16                 matlab.ui.control.Label
        PF_velocity              matlab.ui.control.NumericEditField
        Label_17                 matlab.ui.control.Label
        PF_sample                matlab.ui.control.NumericEditField
        PF_figure                matlab.ui.control.UIAxes
        PF_all_variable          matlab.ui.control.ListBox
        Panel_4                  matlab.ui.container.Panel
        mEditField_2Label        matlab.ui.control.Label
        PF_displayment_size      matlab.ui.control.NumericEditField
        PF_displayment_choose    matlab.ui.control.CheckBox
        sEditField_5Label        matlab.ui.control.Label
        PF_displayment_time      matlab.ui.control.NumericEditField
        sEditField_6Label        matlab.ui.control.Label
        PF_displayment_interval  matlab.ui.control.NumericEditField
        Panel_5                  matlab.ui.container.Panel
        PF_electric_choose       matlab.ui.control.CheckBox
        AEditField_2Label        matlab.ui.control.Label
        PF_electric_size         matlab.ui.control.NumericEditField
        sEditField_7Label        matlab.ui.control.Label
        PF_electric_time         matlab.ui.control.NumericEditField
        sEditField_8Label        matlab.ui.control.Label
        PF_electric_interval     matlab.ui.control.NumericEditField
        Button_plotPF            matlab.ui.control.Button
        Button_PF_pan            matlab.ui.control.Button
        Button_PF_large          matlab.ui.control.Button
        CheckBox_reuse           matlab.ui.control.CheckBox
        Tab_6                    matlab.ui.container.Tab
        impCoefPanel_2           matlab.ui.container.Panel
        Label_19                 matlab.ui.control.Label
        impDataEditField_2       matlab.ui.control.EditField
        impDataButton_2          matlab.ui.control.Button
        Panel_7                  matlab.ui.container.Panel
        RidgeCheckBox            matlab.ui.control.CheckBox
        RecurisionCheckBox       matlab.ui.control.CheckBox
        CalibrateButton          matlab.ui.control.Button
        Effs_List                matlab.ui.control.ListBox
        Tab_5                    matlab.ui.container.Tab
        impCoefPanel             matlab.ui.container.Panel
        impCoefButton            matlab.ui.control.Button
        impDataButton            matlab.ui.control.Button
        compensateButton         matlab.ui.control.Button
        Label_20                 matlab.ui.control.Label
        impCoefEditField         matlab.ui.control.EditField
        Label_21                 matlab.ui.control.Label
        impDataEditField         matlab.ui.control.EditField
        Label_22                 matlab.ui.control.Label
        nSample                  matlab.ui.control.NumericEditField
        Panel_8                  matlab.ui.container.Panel
        p2pValueBeforeLabel      matlab.ui.control.NumericEditField
        p2pValueAfterLabel       matlab.ui.control.NumericEditField
        p2pBeforeLabel           matlab.ui.control.Label
        p2pAfterLabel            matlab.ui.control.Label
        Label_25                 matlab.ui.control.Label
        Compensated_ListBox      matlab.ui.control.ListBox
        Button                   matlab.ui.control.Button
        EvaPanel_JD              matlab.ui.container.Panel
        p2pValueBeforeLabel_JD   matlab.ui.control.NumericEditField
        p2pValueAfterLabel_JD    matlab.ui.control.NumericEditField
        p2pAfterLabel_JD         matlab.ui.control.Label
        p2pBeforeLabel_JD        matlab.ui.control.Label
        Tab_7                    matlab.ui.container.Tab
        UIAxes_CD                matlab.ui.control.UIAxes
        impCoefPanel_3           matlab.ui.container.Panel
        loadData_CD              matlab.ui.control.Button
        ListBox_CD               matlab.ui.control.ListBox
        CheckBox_reuse_CD        matlab.ui.control.CheckBox
        Button_plot_CD           matlab.ui.control.Button
        Button_CD_pan            matlab.ui.control.Button
        Button_CD_large          matlab.ui.control.Button
        userState                matlab.ui.control.Label
    end

    
    properties (Access = private)
        newData
        RemoveGeomagneticData
        total_ele
        electri
        display
        JDdata
        LLH
        CD_datas
        CD_names
        
        filename % 文件名字
        pathname % 文件地址
        JD_data %求系数数据
        PF_data %补偿数据
        effdata %系数数据
        calibrationfunc %求系数方法
        saveeffname %保存的系数文件名字
        eff %系数求解的系数
        
        Eff_filenames % 系数文件名字
        Data_filenames %数据文件名字
        Eff_path %系数文件路径
        Data_path %数据文件路径

    end


    methods (Access = private)

        % Button pushed function: create_PFdata
        function create_PFdataPushed(app, event)
            disp('start create_PFdata');
            addpath(genpath('./data/'),'./function/','./filter/',genpath('./Coefficient/'),genpath('./Figure/'),genpath('./Index/'));
            %             wb = waitbar(0,'开始生成平飞数据');
            Start_Longitude = app.PF_Start_longitude.Value;
            Start_Latitude = app.PF_Start_latitude.Value;
            Start_Height = app.PF_Start_altitude.Value;
            End_Longitude = app.PF_End_longitude.Value;
            End_Latitude = app.PF_End_latitude.Value;
            End_Height = app.PF_End_altitude.Value;
            velocity = app.PF_velocity.Value;
            sample_f = app.PF_sample.Value;
            [lldistance,llhdistance,Y] = calculateDistance(Start_Longitude,Start_Latitude,Start_Height,End_Longitude,End_Latitude,End_Height);
            d = velocity/sample_f;
            saveLLH = [];  %%
            for i = 0:d:llhdistance
                [x,y,z]=distance2loca(Start_Longitude,Start_Latitude,Start_Height,End_Longitude,End_Latitude,End_Height,i,llhdistance,lldistance);
                saveLLH = [saveLLH;x y z];
            end
            saveLLH = addAwgn(saveLLH,0.1);  %%添加噪声
            %             waitbar(.4,wb,'已生成经纬高数据');
            [Pitch,Roll,Yaw] = calculatePRY(size(saveLLH,1),0.1,Y);
            %             waitbar(.6,wb,'已生成姿态角数据');
            electric = zeros(size(Pitch));
            
            displayment = zeros(size(Pitch));
            
            if app.PF_electric_choose.Value == 1
                PF_electric_size_value = app.PF_electric_size.Value;
                PF_electric_time_value = app.PF_electric_time.Value;
                PF_electric_interval_value = app.PF_electric_interval.Value;
                
                total_elec = electornic(PF_electric_size_value,PF_electric_time_value,PF_electric_interval_value,sample_f,size(Pitch,1));
                
                electric = total_elec';
                electric = addAwgn(electric,10);
                app.electri = electric;
            end
            if app.PF_displayment_choose.Value == 1
                PF_displayment_size_value = app.PF_displayment_size.Value;
                PF_displayment_time_value = app.PF_displayment_time.Value;
                PF_displayment_interval_value = app.PF_displayment_interval.Value;
                
                total_disp = displapment(PF_displayment_size_value,PF_displayment_time_value,PF_displayment_interval_value,sample_f,size(Pitch,1));
                displayment = total_disp';
                displayment = addAwgn(displayment,10);
                app.display = displayment;
            end
            mData = [saveLLH,Pitch,Roll,Yaw,electric,displayment];
            [~, ~, ~, ~, ~, ~, X,Y,Z,total,H,latitude,longitude,height,electric_total,displayment_total] = start_simulate(mData);
            Longitude_latitude_altitude = [latitude,longitude,height];
            app.LLH = Longitude_latitude_altitude;
            data = [total,X,Y,Z,Roll,Pitch,Yaw,electric,displayment,H,electric_total,displayment_total,];
            app.newData  =data;
            date_now =  datestr(now,'yyyy-mm-dd');
            date_n = datestr(now,'yyyy-mm-dd_MM');
            savepath= [pwd,'\data\OriginalData\',date_now,'\PF\'];
            if ~exist(savepath,'dir') ~= 0
                mkdir(savepath);
            end
            save([savepath,date_n,'_PF.mat'],'data');
            app.userState.Text = '平飞圈数据生成成功';
            %             waitbar(1,wb,'平飞数据保存成功');
            %             pause(1);
            %             close(wb);
        end

        % Button pushed function: createJDData
        function createJDDataButtonPushed(app, event)
            addpath(genpath('./data/'),'./function/','./filter/',genpath('./Coefficient/'),genpath('./Figure/'),genpath('./Index/'));
            sample_rate = app.JD_sample.Value;
            JD_longitude = app.JD_longitude.Value;
            JD_latitude = app.JD_latitude.Value
            JD_altitdue = app.JD_altitude.Value;
            JD_pitch = app.JD_pitch;
            JD_roll = app.JD_roll;
            JD_yaw = app.JD_yaw;
            %data_file = [JD_longitude JD_latitude JD_altitdue 0 10.97596389 -0.158272237 JD_yaw JD_pitch JD_roll];
            data(1,1) = JD_longitude;
            data(1,2) = JD_latitude;
            data(1,3) = JD_altitdue;
            data(1,4) = 0;
            data(1,5) = 10.97596389;
            data(1,6) = -0.158272237;
            data(1,7) = str2num(JD_yaw.Value);
            data(1,8) = str2num(JD_pitch.Value);
            data(1,9) = str2num(JD_roll.Value);
            
            total_elec = zeros(200*sample_rate,1);
            total_disp = zeros(200*sample_rate,1);
            
            if app.JD_electric_choose.Value == 1
                JD_electric_size = app.JD_electric_size.Value;
                JD_electric_time = app.JD_electric_time.Value;
                JD_electric_interval = app.JD_electric_interval.Value;
                total_elec = electornicJD(JD_electric_size,JD_electric_time,JD_electric_interval,sample_rate);
            end
            if app.JD_displayment_choose.Value == 1
                JD_displayment_size = app.JD_displayment_size.Value;
                JD_displayment_time = app.JD_displayment_time.Value;
                JD_displayment_interval = app.JD_displayment_interval.Value;
                total_disp = displapmentJD(JD_displayment_size,JD_displayment_time,JD_displayment_interval,sample_rate);
            end
            ed_data(:,1) = total_elec;
            ed_data(:,2) = total_disp;
            [~, ~, ~, ~, ~, ~, X_1,Y_1,Z_1,total_1,H_1,~,~,~,electric_total_1,displayment_total_1,roll_1,pitch_1,heading_1,electric_1,displayment_1] = cos_angel(data,sample_rate,ed_data);
            data(1,4) = 90;
            [~, ~, ~, ~, ~, ~, X_2,Y_2,Z_2,total_2,H_2,~,~,~,electric_total_2,displayment_total_2,roll_2,pitch_2,heading_2,electric_2,displayment_2] = cos_angel(data,sample_rate,ed_data);
            data(1,4) = 180;
            [~, ~, ~, ~, ~, ~, X_3,Y_3,Z_3,total_3,H_3,~,~,~,electric_total_3,displayment_total_3,roll_3,pitch_3,heading_3,electric_3,displayment_3] = cos_angel(data,sample_rate,ed_data);
            data(1,4) = 270;
            [~, ~, ~, ~, ~, ~, X_4,Y_4,Z_4,total_4,H_4,~,~,~,electric_total_4,displayment_total_4,roll_4,pitch_4,heading_4,electric_4,displayment_4] = cos_angel(data,sample_rate,ed_data);
            total = [total_1; total_2; total_3; total_4;];
            X = [X_1; X_2; X_3; X_4;];
            Y = [Y_1; Y_2; Y_3; Y_4;];
            Z = [Z_1; Z_2; Z_3; Z_4;];
            H = [H_1; H_2; H_3; H_4;];
            roll = [roll_1;roll_2;roll_3;roll_4;];
            size(roll)
            pitch = [pitch_1;pitch_2;pitch_3;pitch_4;];
            heading = [heading_1;heading_2;heading_3;heading_4;];
            electric = [electric_1;electric_2;electric_3;electric_4;];
            
            displayment = [displayment_1;displayment_2;displayment_3;displayment_4;];
            electric_total = [electric_total_1; electric_total_2; electric_total_3; electric_total_4;];
            
            displayment_total = [displayment_total_1; displayment_total_2; displayment_total_3; displayment_total_4;];
            %             plot(app.JD_figure, total,'-r');
            
            data = [total,X,Y,Z,roll,pitch,heading,electric,displayment,H,electric_total,displayment_total,];
            app.JDdata = data;
            date_now =  datestr(now,'yyyy-mm-dd');
            date_n = datestr(now,'yyyy-mm-dd_MM');
            savepath= [pwd,'\data\OriginalData\',date_now,'\JD\'];
            if ~exist(savepath,'dir') ~= 0
                mkdir(savepath);
            end
            save([savepath,date_n,'_JD.mat'],'data');
            app.userState.Text = '机动圈数据生成成功';
        end

        % Button pushed function: Button_plotPF
        function Button_plotPFPushed(app, event)
            a = get(app.PF_all_variable,'Value');
            %             [Hex, Hey, Hez, X,Y,Z,total,H,latitude,longitude,height];
            if app.CheckBox_reuse.Value == 1
                hold(app.PF_figure,'on');
            elseif app.CheckBox_reuse.Value == 0
                cla(app.PF_figure);
            end
            if strcmp(a,'地磁场')
                plot(app.PF_figure,app.newData(:,10));
            elseif strcmp(a,'经纬度')
                plot(app.PF_figure,app.LLH(:,2),app.LLH(:,1));
            elseif strcmp(a,'高度')
                plot(app.PF_figure,app.LLH(:,3));
            elseif strcmp(a,'总场')
                plot(app.PF_figure,app.newData(:,1));
            elseif strcmp(a,'三分量')
                plot(app.PF_figure,app.newData(:,2));
                hold(app.PF_figure,'on');
                plot(app.PF_figure,app.newData(:,3));
                hold(app.PF_figure,'on');
                plot(app.PF_figure,app.newData(:,4));
            elseif strcmp(a,'用电设备磁干扰')
                plot(app.PF_figure,app.newData(:,11));
            elseif strcmp(a,'机械位移磁干扰')
                plot(app.PF_figure,app.newData(:,12));
            elseif strcmp(a,'电流大小')
                plot(app.PF_figure, app.electri);
            elseif strcmp(a,'机械位移')
                plot(app.PF_figure,app.display);
            end
            axis(app.PF_figure,'auto');
        end

        % Button pushed function: Button_PF_large
        function Button_PF_largePushed(app, event)
            pan(app.PF_figure,'off');
            zoom(app.PF_figure,'on');
        end

        % Button pushed function: Button_PF_pan
        function Button_PF_panPushed(app, event)
            zoom(app.PF_figure,'off');
            pan(app.PF_figure,'on');
        end

        % Button pushed function: Button_plotJD
        function Button_plotJDPushed(app, event)
            a = get(app.JD_all_variable,'Value');
            %             [Hex, Hey, Hez, X,Y,Z,total,H,latitude,longitude,height];
            if app.CheckBox_reuse_2.Value == 1
                hold(app.JD_figure,'on');
            elseif app.CheckBox_reuse_2.Value == 0
                cla(app.JD_figure);
            end
            if strcmp(a,'地磁场')
                plot(app.JD_figure,app.JDdata(:,10));
            elseif strcmp(a,'总场')
                plot(app.JD_figure,app.JDdata(:,1));
            elseif strcmp(a,'三分量')
                plot(app.JD_figure,app.JDdata(:,2));
                hold(app.JD_figure,'on');
                plot(app.JD_figure,app.JDdata(:,3));
                hold(app.JD_figure,'on');
                plot(app.JD_figure,app.JDdata(:,4));
            elseif strcmp(a,'用电设备磁干扰')
                plot(app.JD_figure,app.JDdata(:,11));
            elseif strcmp(a,'机械位移磁干扰')
                plot(app.JD_figure,app.JDdata(:,12));
            end
            axis(app.JD_figure,'auto');
        end

        % Button pushed function: Button_JD_large
        function Button_JD_largePushed(app, event)
            pan(app.JD_figure,'off');
            zoom(app.JD_figure,'on');
        end

        % Button pushed function: Button_JD_pan
        function Button_JD_panPushed(app, event)
            zoom(app.JD_figure,'off');
            pan(app.JD_figure,'on');
        end

        % Button pushed function: Button_CD_large
        function Button_CD_largePushed(app, event)
            pan(app.UIAxes_CD,'off');
            zoom(app.UIAxes_CD,'on');
        end

        % Button pushed function: Button_CD_pan
        function Button_CD_panPushed(app, event)
            zoom(app.UIAxes_CD,'off');
            pan(app.UIAxes_CD,'on');
        end

        % Button pushed function: Button_plot_CD
        function Button_plot_CDPushed(app, event)
            a = get(app.ListBox_CD,'Value');
            n = 1;
            CDnames = app.CD_names;
            CDdatas = app.CD_datas;
            for i = 1:size(CDnames,2)
                if strcmp(a,CDnames(1,i))
                    n = i;
                end
            end
            CDdata = CDdatas(1,n).data;
            axis(app.UIAxes_CD,'auto');
            if app.CheckBox_reuse_CD.Value
                hold(app.UIAxes_CD,'on');
                plot(app.UIAxes_CD,CDdata(:,2));
            else
                cla(app.UIAxes_CD);
                plot(app.UIAxes_CD,CDdata(:,2));
            end
            app.userState.Text = '图像绘制成功';
        end

        % Button pushed function: loadData_CD
        function loadData_CDButtonPushed(app, event)
            [filename,pathname] = uigetfile('.mat','选择导入补偿数据','MultiSelect','on');
            if isequal(filename,0)
                app.userState.Text = '用户取消选择';
            else

                CD_datas = [];
                CD_names = [];
                try
                for i =1:size(filename,2)
                    newfilename = char(filename(i));
                    pathfile = [pathname,newfilename];
                    CDdata = load(pathfile,'-mat');
                    CD_datas = [CD_datas,CDdata];
                    CD_names = [CD_names,string(newfilename)];
                end
                catch
                    newfilename = char(filename);
                    pathfile = [pathname,newfilename];
                    CDdata = load(char(pathfile),'-mat');
                    CD_datas = [CD_datas,CDdata];
                    CD_names = [CD_names,string(newfilename)];
                end
                app.CD_datas = CD_datas;
                app.CD_names = CD_names
                app.ListBox_CD.Items = CD_names;
                app.userState.Text = '补偿数据导入成功';
                
            end
        end

        % Button pushed function: impDataButton_2
        function impDataButton_2Pushed(app, event)
            addpath('D:\dataproc\2019-05-三亚塞斯纳-数据库\function\');
            [app.filename,app.pathname] = uigetfile('*.mat','选择原始数据');
            datapath = [app.pathname,app.filename];
            if all(datapath==0)
            else
                app.impDataEditField_2.Value = datapath;
                app.JD_data = load(datapath);
                app.JD_data = app.JD_data.data;
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.saveeffname = app.filename(1:end-4);
            app.eff = zeros(37,1);%先将临时系数文件清零
            if(app.RidgeCheckBox.Value==0&&app.RecurisionCheckBox.Value==0)
                h = msgbox('请选择完整求系数方案');
            else
                total = app.JD_data(:,1);
                vX = app.JD_data(:,2);
                vY = app.JD_data(:,3);
                vZ = app.JD_data(:,4);
                [vCosX, vCosY, vCosZ] = calculate_cosine(vX, vY, vZ);
                [vDCosX, vDCosY, vDCosZ] = calculate_derivate(vCosX, vCosY, vCosZ);
                
                mtl_Model = [vCosX, vCosY, vCosZ, vCosX.^2, vCosX.*vCosY, vCosX.*vCosZ, vCosY.^2, vCosY.*vCosZ, ...
                    vCosX.*vDCosX, vCosX.*vDCosY, vCosX.*vDCosZ, vCosY.*vDCosX, vCosY.*vDCosY, vCosY.*vDCosZ,...
                    vCosZ.*vDCosX, vCosZ.*vDCosY];
                vElc = app.JD_data(:,11);
                if any(vElc ~=0)
                    vDElc = [0;diff(vElc)]*app.nSample.Value;
                    FiltedTotal = filter_bandpass_calibration(total);
                    mEle_Model = [vCosX.*vElc,vCosY.*vElc,vCosZ.*vElc,vDElc.*vCosX,vDElc.*vCosY,vDElc.*vCosZ];
                    
                    Filtered_mEle_Model = filter_bandpass_calibration(mEle_Model);
                    
                    vCoefficients_Ele = app.calibrationfunc(FiltedTotal,Filtered_mEle_Model);
                    app.eff(1:6,1)= vCoefficients_Ele;
                    total  =  total - mEle_Model*vCoefficients_Ele;
                    app.saveeffname = [app.saveeffname,'加用电'];
                end
                
                
                vL = app.JD_data(:,12);
                if(any(vL ~=0))
                    mM_model = [vCosX,vCosX.*vL,vCosX.*(vL.^2),vCosX.*(vL.^3),vCosY,vCosY.*vL,vCosY.*(vL.^2),vCosY.*(vL.^3),...
                        vCosZ,vCosZ.*vL,vCosZ.*(vL.^2),vCosZ.*(vL.^3)];
                    FiltedTotal = filter_bandpass_calibration(total);
                    Filtered_mM_Model = filter_bandpass_calibration(mM_model);
                    
                    vCoefficients_M = app.calibrationfunc(FiltedTotal,Filtered_mM_Model);
                    app.eff(7:18,1)= vCoefficients_M;
                    total  =  total - mM_model*vCoefficients_M;
                    
                    app.saveeffname = [app.saveeffname,'加机械位移'];
                end
                %%
                
                FiltedTotal = filter_bandpass_calibration(total);
                
                Filterd_mtl_Model = filter_bandpass_calibration(mtl_Model);
                
                vCoefficients_TL = app.calibrationfunc(FiltedTotal,Filterd_mtl_Model);
                
                app.eff(19:34,1) = vCoefficients_TL;
                
                app.Effs_List.Items = [app.Effs_List.Items,app.saveeffname];%系数显示
                app.eff = [app.eff,app.eff];
                
                msgbox('求解完成');
                result = saveeff(app.saveeffname,app.eff)
                
            end
        end

        % Value changed function: RecurisionCheckBox
        function RecurisionCheckBoxValueChanged(app, event)
            value = app.RecurisionCheckBox.Value;
            app.RidgeCheckBox.Value = 0;
            app.calibrationfunc = @recursive_least_square_hit;
        end

        % Value changed function: RidgeCheckBox
        function RidgeCheckBoxValueChanged(app, event)
            value = app.RidgeCheckBox.Value;
            app.RecurisionCheckBox.Value = 0;
            app.calibrationfunc = @recursive_least_square_hit;
        end

        % Button pushed function: impCoefButton
        function impCoefButtonPushed(app, event)
            [filenames,app.Eff_path]=uigetfile('*.mat','选择原始系数','MultiSelect','on');
            try
                d = filenames{1};
                
                app.Eff_filenames = filenames;
                
            catch
                
                app.Eff_filenames = {filenames};
            end
            
            if all(app.Eff_path==0)
            else
                
                
                app.impCoefEditField.Value = app.Eff_path ;
                
            end
            %             if isempty(filepath) == 0
            %

        end

        % Button pushed function: impDataButton
        function impDataButtonPushed(app, event)
            [filenames,app.Data_path]=uigetfile('*.mat','选择原始数据','MultiSelect','on');
            
            try
                d = filenames{1};
                
                app.Data_filenames = filenames;
                
            catch
                
                app.Data_filenames = {filenames};
            end
            if all(app.Data_path==0)
            else
                app.impDataEditField.Value = app.Eff_path ;
            end
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fs = app.nSample.Value;
            path = app.Compensated_ListBox.Value;
            index = path(1,[32,33]);
            
            total = load(path);
            total = total.data;
            vFilteredTotal =filter_bandpass_index(total(:,1));
            vFilteredCompensatedTotal = filter_bandpass_index(total(:,2));
            if(index == 'PF')
                signal = vFilteredTotal;
                len = length(signal);
                len_5min = 300*fs;%fs=10   %%%根据采样频率改%%%
                len_30s = 30*fs;
                
                ppv_30s = [];
                for j = len_30s:len
                    win_30s = j - len_30s + 1 : j;
                    seg_30s = signal(win_30s);
                    ppv = max(seg_30s) - min(seg_30s);
                    ppv_30s = [ppv_30s;ppv];
                end
                [best_30s, idx_30s] = min(ppv_30s);
                
                len_30s_in_5min = len_5min - len_30s + 1;
                ppv_5min = [];
                for i = len_30s_in_5min:1:length(ppv_30s)
                    win_30s_in_5min = i - len_30s_in_5min + 1 : i;
                    seg_ppv_30s_in_5min = ppv_30s(win_30s_in_5min);
                    ppv_max_30s = max(seg_ppv_30s_in_5min);
                    ppv_5min = [ppv_5min;ppv_max_30s];
                end
                ppv_5min_before = min(ppv_5min);
                
                %[~,~,~,~,ppv_5min] = calculate_index_30(vFilteredCompensatedTotal);
                signal = vFilteredCompensatedTotal;
                len = length(signal);
                len_5min = 3000;%fs=10   %%%根据采样频率改%%%
                len_30s = 300;
                
                ppv_30s = [];
                for j = len_30s:len
                    win_30s = j - len_30s + 1 : j;
                    seg_30s = signal(win_30s);
                    ppv = max(seg_30s) - min(seg_30s);
                    ppv_30s = [ppv_30s;ppv];
                end
                [best_30s, idx_30s] = min(ppv_30s);
                
                len_30s_in_5min = len_5min - len_30s + 1;
                ppv_5min = [];
                for i = len_30s_in_5min:1:length(ppv_30s)
                    win_30s_in_5min = i - len_30s_in_5min + 1 : i;
                    seg_ppv_30s_in_5min = ppv_30s(win_30s_in_5min);
                    ppv_max_30s = max(seg_ppv_30s_in_5min);
                    ppv_5min = [ppv_5min;ppv_max_30s];
                end
                ppv_5min_after = min(ppv_5min);
                
                p2pBefore = ppv_5min_before;
                p2pAfter = ppv_5min_after;
                app.p2pValueBeforeLabel.Value = p2pBefore;
                app.p2pValueAfterLabel.Value = p2pAfter;
                indexpath = ['index/',path(18:end-4),'5min30s.mat'];
                save(indexpath,'p2pBefore','p2pAfter');
            else
                [FOM,IR] = JD_index(vFilteredTotal,vFilteredCompensatedTotal,fs);
                app.p2pValueBeforeLabel_JD.Value = FOM;
                app.p2pValueAfterLabel_JD.Value = IR;
                indexpath = ['index/',path(18:end-4),'FOM_IR.mat'];
                save(indexpath,'FOM','IR');
            end
            
            
        end

        % Button pushed function: compensateButton
        function compensateButtonPushed(app, event)
           
            [row1,col1] = size(app.Eff_filenames)
            [row2,col2] = size(app.Data_filenames)
            for i = 1:col1
                for j = 1:col2
                    filepath = [app.Data_path,app.Data_filenames{j}];
                    coeffpath = [app.Eff_path,app.Eff_filenames{i}];
                    data = load(filepath);
                    data = data.data;
                    coeff = load(coeffpath);
                    coeff = coeff.data;
                    total = data(:,1);
                    oritotal = total;
                    %             tmptotal(:,1) = total;
                    vX = data(:,2);
                    vY = data(:,3);
                    vZ = data(:,4);
                    [vCosX, vCosY, vCosZ] = calculate_cosine(vX, vY, vZ);
                    [vDCosX, vDCosY, vDCosZ] = calculate_derivate(vCosX, vCosY, vCosZ);
                    %             i = 2;%存第二列
                    %             if(strcmp(app.Compen_ListBox.Value,'加电流补偿')||strcmp(app.Compen_ListBox.Value,'加电流机械位移补偿'))
                    vElc = data(:,11);
                    vDElc = [0;diff(vElc)]*10;%10是采样率 可以在改变
                    eff_Ele = coeff(1:6,1);
                    mEle_Model = [vCosX.*vElc,vCosY.*vElc,vCosZ.*vElc,vDElc.*vCosX,vDElc.*vCosY,vDElc.*vCosZ];
                    total = total - mEle_Model*eff_Ele;
                    %             i = i+1;
                    
                    %             end
                    %             if(strcmp(app.Compen_ListBox.Value,'加机械位移补偿')||strcmp(app.Compen_ListBox.Value,'加电流机械位移补偿'))
                    eff_M = coeff(7:18,1);
                    vL = data(:,12);
                    mM_model = [vCosX,vCosX.*vL,vCosX.*(vL.^2),vCosX.*(vL.^3),vCosY,vCosY.*vL,vCosY.*(vL.^2),vCosY.*(vL.^3),...
                        vCosZ,vCosZ.*vL,vCosZ.*(vL.^2),vCosZ.*(vL.^3)];
                    total = total - mM_model*eff_M;
                    TL_Model = [vCosX, vCosY, vCosZ, vCosX.^2, vCosX.*vCosY, vCosX.*vCosZ, vCosY.^2, vCosY.*vCosZ, ...
                        vCosX.*vDCosX, vCosX.*vDCosY, vCosX.*vDCosZ, vCosY.*vDCosX, vCosY.*vDCosY, vCosY.*vDCosZ,...
                        vCosZ.*vDCosX, vCosZ.*vDCosY,];
                    eff_TL = coeff([19:34],1);
                    total = total - TL_Model * eff_TL;
                    
                    compensatedtotal = total;
                    
                    date_now = datestr(now,'yyyy-mm-dd');
                    dirname = ['data/compensated'];
                    data = [oritotal,compensatedtotal];
                    total_filename = [app.Data_filenames{j}(1:end-4) ,'数据经过' , app.Eff_filenames{i}(1:end-4) ,'补偿后.mat'];
                    
                    if(exist(dirname,'dir')==0)
                        mkdir(dirname)
                    else
                    end
                    total_filename = [dirname,'/',total_filename];
                    
                    save(total_filename,'data');
                    app.Compensated_ListBox.Items{end+1} = total_filename;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '磁干扰数据仿真';
            app.TabGroup2 = uitabgroup(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '机动圈数据仿真';
            app.Panel_2 = uipanel(app.Tab_3);
            app.Panel_2.Title = '机械位移数据仿真';
            app.mEditFieldLabel = uilabel(app.Panel_2);
            app.mEditFieldLabel.Text = '钢索位移量（m）';
            app.JD_displayment_size = uieditfield(app.Panel_2, 'numeric');
            app.JD_displayment_size.Value = 2;
            app.JD_displayment_choose = uicheckbox(app.Panel_2);
            app.JD_displayment_choose.Text = '选择';
            app.sLabel_2 = uilabel(app.Panel_2);
            app.sLabel_2.Text = '持续时间（s）';
            app.JD_displayment_time = uieditfield(app.Panel_2, 'numeric');
            app.JD_displayment_time.Value = 5;
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = '时间间隔（s）';
            app.JD_displayment_interval = uieditfield(app.Panel_2, 'numeric');
            app.JD_displayment_interval.Value = 20;
            app.JD_figure = uiaxes(app.Tab_3);
            xlabel(app.JD_figure, 'X')
            ylabel(app.JD_figure, 'Y')
            app.Panel_3 = uipanel(app.Tab_3);
            app.EditField_2Label = uilabel(app.Panel_3);
            app.EditField_2Label.Text = '纬度';
            app.JD_latitude = uieditfield(app.Panel_3, 'numeric');
            app.JD_latitude.Value = 17.15710873;
            app.EditField_3Label = uilabel(app.Panel_3);
            app.EditField_3Label.Text = '高度';
            app.JD_altitude = uieditfield(app.Panel_3, 'numeric');
            app.JD_altitude.Value = 3201.379;
            app.pitchLabel = uilabel(app.Panel_3);
            app.pitchLabel.Text = 'pitch角';
            app.JD_pitch = uidropdown(app.Panel_3);
            app.JD_pitch.Items = {'5', '10', '15', '20'};
            app.JD_pitch.Value = '5';
            app.rollLabel = uilabel(app.Panel_3);
            app.rollLabel.Text = 'roll角';
            app.JD_roll = uidropdown(app.Panel_3);
            app.JD_roll.Items = {'5', '10', '15', '20'};
            app.JD_roll.Value = '5';
            app.yawLabel = uilabel(app.Panel_3);
            app.yawLabel.Text = 'yaw角';
            app.JD_yaw = uidropdown(app.Panel_3);
            app.JD_yaw.Items = {'5', '10', '15', '20'};
            app.JD_yaw.Value = '5';
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = '经度';
            app.JD_longitude = uieditfield(app.Panel_3, 'numeric');
            app.JD_longitude.Value = 110.1292467;
            app.Label_18 = uilabel(app.Panel_3);
            app.Label_18.Text = '采样率';
            app.JD_sample = uieditfield(app.Panel_3, 'numeric');
            app.JD_sample.Value = 10;
            app.Panel = uipanel(app.Tab_3);
            app.Panel.Title = '用电设备数据仿真';
            app.JD_electric_choose = uicheckbox(app.Panel);
            app.JD_electric_choose.Text = '选择';
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Text = '电流大小（A）';
            app.JD_electric_size = uieditfield(app.Panel, 'numeric');
            app.JD_electric_size.Value = 1;
            app.sEditField_3Label = uilabel(app.Panel);
            app.sEditField_3Label.Text = '持续时间（s）';
            app.JD_electric_time = uieditfield(app.Panel, 'numeric');
            app.JD_electric_time.Value = 1;
            app.sEditField_4Label = uilabel(app.Panel);
            app.sEditField_4Label.Text = '时间间隔（s）';
            app.JD_electric_interval = uieditfield(app.Panel, 'numeric');
            app.JD_electric_interval.Value = 3;
            app.createJDData = uibutton(app.Tab_3, 'push');
            app.createJDData.ButtonPushedFcn = createCallbackFcn(app, @createJDDataButtonPushed, true);
            app.createJDData.Text = '生成机动圈数据';
            app.JD_all_variable = uilistbox(app.Tab_3);
            app.JD_all_variable.Items = {'总场', '地磁场', '三分量', '用电设备磁干扰', '机械位移磁干扰', '电流大小', '位移大小'};
            app.JD_all_variable.Multiselect = 'on';
            app.JD_all_variable.Value = {'总场'};
            app.Button_plotJD = uibutton(app.Tab_3, 'push');
            app.Button_plotJD.ButtonPushedFcn = createCallbackFcn(app, @Button_plotJDPushed, true);
            app.Button_plotJD.Text = '绘制';
            app.CheckBox_reuse_2 = uicheckbox(app.Tab_3);
            app.CheckBox_reuse_2.Text = '重用图窗';
            app.Button_JD_pan = uibutton(app.Tab_3, 'push');
            app.Button_JD_pan.ButtonPushedFcn = createCallbackFcn(app, @Button_JD_panPushed, true);
            app.Button_JD_pan.Text = '平移';
            app.Button_JD_large = uibutton(app.Tab_3, 'push');
            app.Button_JD_large.ButtonPushedFcn = createCallbackFcn(app, @Button_JD_largePushed, true);
            app.Button_JD_large.Text = '+/-';
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '平飞段数据仿真';
            app.create_PFdata = uibutton(app.Tab_4, 'push');
            app.create_PFdata.ButtonPushedFcn = createCallbackFcn(app, @create_PFdataPushed, true);
            app.create_PFdata.Text = '生成平飞段数据';
            app.Label_10 = uilabel(app.Tab_4);
            app.Label_10.Text = '起点经度';
            app.PF_Start_longitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_longitude.Limits = [-180 180];
            app.PF_Start_longitude.ValueDisplayFormat = '%.10f';
            app.PF_Start_longitude.Value = 110.1292467;
            app.Label_11 = uilabel(app.Tab_4);
            app.Label_11.Text = '终点经度';
            app.PF_End_longitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_longitude.Limits = [-180 180];
            app.PF_End_longitude.ValueDisplayFormat = '%.10f';
            app.PF_End_longitude.Value = 110.1173971;
            app.Label_12 = uilabel(app.Tab_4);
            app.Label_12.Text = '起点纬度';
            app.PF_Start_latitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_latitude.Limits = [-90 90];
            app.PF_Start_latitude.ValueDisplayFormat = '%.10f';
            app.PF_Start_latitude.Value = 17.15710873;
            app.Label_13 = uilabel(app.Tab_4);
            app.Label_13.Text = '终点纬度';
            app.PF_End_latitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_latitude.Limits = [-90 90];
            app.PF_End_latitude.ValueDisplayFormat = '%.10f';
            app.PF_End_latitude.Value = 17.40880123;
            app.Label_14 = uilabel(app.Tab_4);
            app.Label_14.Text = '起点高度';
            app.PF_Start_altitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_Start_altitude.Limits = [0 Inf];
            app.PF_Start_altitude.ValueDisplayFormat = '%.3f';
            app.PF_Start_altitude.Value = 3201.379;
            app.Label_15 = uilabel(app.Tab_4);
            app.Label_15.Text = '终点高度';
            app.PF_End_altitude = uieditfield(app.Tab_4, 'numeric');
            app.PF_End_altitude.Limits = [0 Inf];
            app.PF_End_altitude.ValueDisplayFormat = '%.3f';
            app.PF_End_altitude.Value = 3568;
            app.Label_16 = uilabel(app.Tab_4);
            app.Label_16.Text = '载体速度';
            app.PF_velocity = uieditfield(app.Tab_4, 'numeric');
            app.PF_velocity.Value = 60;
            app.Label_17 = uilabel(app.Tab_4);
            app.Label_17.Text = '采样率';
            app.PF_sample = uieditfield(app.Tab_4, 'numeric');
            app.PF_sample.Value = 10;
            app.PF_figure = uiaxes(app.Tab_4);
            xlabel(app.PF_figure, 'X')
            ylabel(app.PF_figure, 'Y')
            app.PF_all_variable = uilistbox(app.Tab_4);
            app.PF_all_variable.Items = {'经纬度', '高度', '地磁场', '总场', '三分量', '用电设备磁干扰', '机械位移磁干扰', '电流大小', '机械位移'};
            app.PF_all_variable.Multiselect = 'on';
            app.PF_all_variable.Value = {'经纬度'};
            app.Panel_4 = uipanel(app.Tab_4);
            app.Panel_4.Title = '机械位移数据仿真';
            app.mEditField_2Label = uilabel(app.Panel_4);
            app.mEditField_2Label.Text = '钢索位移量（m）';
            app.PF_displayment_size = uieditfield(app.Panel_4, 'numeric');
            app.PF_displayment_size.Value = 2;
            app.PF_displayment_choose = uicheckbox(app.Panel_4);
            app.PF_displayment_choose.Text = '选择';
            app.sEditField_5Label = uilabel(app.Panel_4);
            app.sEditField_5Label.Text = '持续时间（s）';
            app.PF_displayment_time = uieditfield(app.Panel_4, 'numeric');
            app.PF_displayment_time.Value = 5;
            app.sEditField_6Label = uilabel(app.Panel_4);
            app.sEditField_6Label.Text = '时间间隔（s）';
            app.PF_displayment_interval = uieditfield(app.Panel_4, 'numeric');
            app.PF_displayment_interval.Value = 20;
            app.Panel_5 = uipanel(app.Tab_4);
            app.Panel_5.Title = '用电设备数据仿真';
            app.PF_electric_choose = uicheckbox(app.Panel_5);
            app.PF_electric_choose.Text = '选择';
            app.AEditField_2Label = uilabel(app.Panel_5);
            app.AEditField_2Label.Text = '电流大小（A）';
            app.PF_electric_size = uieditfield(app.Panel_5, 'numeric');
            app.PF_electric_size.Value = 1;
            app.sEditField_7Label = uilabel(app.Panel_5);
            app.sEditField_7Label.Text = '持续时间（s）';
            app.PF_electric_time = uieditfield(app.Panel_5, 'numeric');
            app.PF_electric_time.Value = 1;
            app.sEditField_8Label = uilabel(app.Panel_5);
            app.sEditField_8Label.Text = '时间间隔（s）';
            app.PF_electric_interval = uieditfield(app.Panel_5, 'numeric');
            app.PF_electric_interval.Value = 3;
            app.Button_plotPF = uibutton(app.Tab_4, 'push');
            app.Button_plotPF.ButtonPushedFcn = createCallbackFcn(app, @Button_plotPFPushed, true);
            app.Button_plotPF.Text = '绘制';
            app.Button_PF_pan = uibutton(app.Tab_4, 'push');
            app.Button_PF_pan.ButtonPushedFcn = createCallbackFcn(app, @Button_PF_panPushed, true);
            app.Button_PF_pan.Text = '平移';
            app.Button_PF_large = uibutton(app.Tab_4, 'push');
            app.Button_PF_large.ButtonPushedFcn = createCallbackFcn(app, @Button_PF_largePushed, true);
            app.Button_PF_large.Text = '+/-';
            app.CheckBox_reuse = uicheckbox(app.Tab_4);
            app.CheckBox_reuse.Text = '重用图窗';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '系数求解';
            app.impCoefPanel_2 = uipanel(app.Tab_6);
            app.impCoefPanel_2.Title = '导入机动圈数据';
            app.Label_19 = uilabel(app.impCoefPanel_2);
            app.Label_19.Text = '数据路径';
            app.impDataEditField_2 = uieditfield(app.impCoefPanel_2, 'text');
            app.impDataButton_2 = uibutton(app.impCoefPanel_2, 'push');
            app.impDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @impDataButton_2Pushed, true);
            app.impDataButton_2.Text = '导入数据';
            app.Panel_7 = uipanel(app.Tab_6);
            app.Panel_7.Title = '系数求解算法';
            app.RidgeCheckBox = uicheckbox(app.Panel_7);
            app.RidgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RidgeCheckBoxValueChanged, true);
            app.RidgeCheckBox.Text = '岭回归算法';
            app.RecurisionCheckBox = uicheckbox(app.Panel_7);
            app.RecurisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecurisionCheckBoxValueChanged, true);
            app.RecurisionCheckBox.Text = '递推最小二乘法';
            app.CalibrateButton = uibutton(app.Tab_6, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = '系数求解';
            app.Effs_List = uilistbox(app.Tab_6);
            app.Effs_List.Items = {};
            app.Effs_List.Value = {};
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '数据补偿';
            app.impCoefPanel = uipanel(app.Tab_5);
            app.impCoefPanel.Title = '导入系数文件和平飞段数据文件（可同时选择多个文件）';
            app.impCoefButton = uibutton(app.impCoefPanel, 'push');
            app.impCoefButton.ButtonPushedFcn = createCallbackFcn(app, @impCoefButtonPushed, true);
            app.impCoefButton.Text = '导入系数';
            app.impDataButton = uibutton(app.impCoefPanel, 'push');
            app.impDataButton.ButtonPushedFcn = createCallbackFcn(app, @impDataButtonPushed, true);
            app.impDataButton.Text = '导入待补偿数据';
            app.compensateButton = uibutton(app.impCoefPanel, 'push');
            app.compensateButton.ButtonPushedFcn = createCallbackFcn(app, @compensateButtonPushed, true);
            app.compensateButton.Text = '补偿';
            app.Label_20 = uilabel(app.impCoefPanel);
            app.Label_20.Text = '系数文件';
            app.impCoefEditField = uieditfield(app.impCoefPanel, 'text');
            app.Label_21 = uilabel(app.impCoefPanel);
            app.Label_21.Text = '待补偿文件';
            app.impDataEditField = uieditfield(app.impCoefPanel, 'text');
            app.Label_22 = uilabel(app.impCoefPanel);
            app.Label_22.Text = '采样率';
            app.nSample = uieditfield(app.impCoefPanel, 'numeric');
            app.Panel_8 = uipanel(app.Tab_5);
            app.Panel_8.Title = '评价指标（平飞段）';
            app.p2pValueBeforeLabel = uieditfield(app.Panel_8, 'numeric');
            app.p2pValueAfterLabel = uieditfield(app.Panel_8, 'numeric');
            app.p2pBeforeLabel = uilabel(app.Panel_8);
            app.p2pBeforeLabel.Text = '补偿前峰峰值';
            app.p2pAfterLabel = uilabel(app.Panel_8);
            app.p2pAfterLabel.Text = '补偿后峰峰值';
            app.Label_25 = uilabel(app.Tab_5);
            app.Label_25.Text = '补偿后总场';
            app.Compensated_ListBox = uilistbox(app.Tab_5);
            app.Compensated_ListBox.Items = {};
            app.Compensated_ListBox.Value = {};
            app.Button = uibutton(app.Tab_5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '指标求解';
            app.EvaPanel_JD = uipanel(app.Tab_5);
            app.EvaPanel_JD.Title = '评价指标（机动圈）';
            app.p2pValueBeforeLabel_JD = uieditfield(app.EvaPanel_JD, 'numeric');
            app.p2pValueAfterLabel_JD = uieditfield(app.EvaPanel_JD, 'numeric');
            app.p2pAfterLabel_JD = uilabel(app.EvaPanel_JD);
            app.p2pAfterLabel_JD.Text = '改善比';
            app.p2pBeforeLabel_JD = uilabel(app.EvaPanel_JD);
            app.p2pBeforeLabel_JD.Text = 'FOM值';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '图形绘制';
            app.UIAxes_CD = uiaxes(app.Tab_7);
            title(app.UIAxes_CD, 'Title')
            xlabel(app.UIAxes_CD, 'X')
            ylabel(app.UIAxes_CD, 'Y')
            app.impCoefPanel_3 = uipanel(app.Tab_7);
            app.impCoefPanel_3.Title = '导入补偿后数据（可同时选择多个文件）';
            app.loadData_CD = uibutton(app.impCoefPanel_3, 'push');
            app.loadData_CD.ButtonPushedFcn = createCallbackFcn(app, @loadData_CDButtonPushed, true);
            app.loadData_CD.Text = '导入数据';
            app.ListBox_CD = uilistbox(app.Tab_7);
            app.ListBox_CD.Items = {};
            app.ListBox_CD.Value = {};
            app.CheckBox_reuse_CD = uicheckbox(app.Tab_7);
            app.CheckBox_reuse_CD.Text = '重用图窗';
            app.Button_plot_CD = uibutton(app.Tab_7, 'push');
            app.Button_plot_CD.ButtonPushedFcn = createCallbackFcn(app, @Button_plot_CDPushed, true);
            app.Button_plot_CD.Text = '绘制';
            app.Button_CD_pan = uibutton(app.Tab_7, 'push');
            app.Button_CD_pan.ButtonPushedFcn = createCallbackFcn(app, @Button_CD_panPushed, true);
            app.Button_CD_pan.Text = '平移';
            app.Button_CD_large = uibutton(app.Tab_7, 'push');
            app.Button_CD_large.ButtonPushedFcn = createCallbackFcn(app, @Button_CD_largePushed, true);
            app.Button_CD_large.Text = '+/-';
            app.userState = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end